classdef App_EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        OpenMenu         matlab.ui.container.Menu
        ExportMenu       matlab.ui.container.Menu
        ExportasymMenu   matlab.ui.container.Menu
        ClosefilesMenu   matlab.ui.container.Menu
        QuitMenu         matlab.ui.container.Menu
        AnalysisMenu     matlab.ui.container.Menu
        GraphMenu        matlab.ui.container.Menu
        ApEnMenu         matlab.ui.container.Menu
        PwelchMenu       matlab.ui.container.Menu
        STFTMenu         matlab.ui.container.Menu
        CWTMenu          matlab.ui.container.Menu
        TopographyMenu   matlab.ui.container.Menu
        AmplitudeMenu    matlab.ui.container.Menu
        FrequencyMenu    matlab.ui.container.Menu
        EntropyApEnMenu  matlab.ui.container.Menu
        MainGrid         matlab.ui.container.GridLayout
        GridLayout3      matlab.ui.container.GridLayout
        GridLayout4      matlab.ui.container.GridLayout
        GridLayout6      matlab.ui.container.GridLayout
        MWSel            matlab.ui.control.DropDown
        BandSel          matlab.ui.control.DropDown
        GridLayout5      matlab.ui.container.GridLayout
        TimeWindow       matlab.ui.control.Spinner
        TimeOffset       matlab.ui.control.Spinner
        TimeSlider       matlab.ui.control.RangeSlider
        Axes             matlab.ui.control.UIAxes
        TabGroup         matlab.ui.container.TabGroup
        FilesTab         matlab.ui.container.Tab
        GridLayout       matlab.ui.container.GridLayout
        FileList         matlab.ui.control.ListBox
        ElectrodesTab    matlab.ui.container.Tab
        GridLayout2      matlab.ui.container.GridLayout
        ElectrodeList    matlab.ui.control.ListBox
        RegionList       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Files cell = {}
        CurrentFile = 0
        IsSliderSyncing logical = false
        CurrentWidth = 1
    end
    
    properties (Access = public)
        Regions = struct()
        AsymPairs = struct()
    end
    
    methods (Access = public)
        function setAxesLimit(app, label, xlimv)
            if isempty(app.getFile())
                return
            end

            info = app.getFile().Fileinfo;
            ndatarecords = info.NumDataRecords;
            duration = seconds(info.DataRecordDuration);
            idx = app.getElectrodeIndex(label);

            if isempty(xlimv)
                xlimv = [0 ndatarecords]*duration;
            end

            disp(xlimv);

            xlim(app.Axes, xlimv);
            ylim(app.Axes, ...
                [info.PhysicalMin(idx) info.PhysicalMax(idx)]);
        end
        
        function file = getFile(app)
            if app.CurrentFile == 0
                file = [];
            else
                file = app.Files{app.CurrentFile};
            end
        end

        function selectFile(app, value)
            app.CurrentFile = value;
            app.updateTimeLimits(0);
            app.TimeSlider.Enable = "on";
            app.TimeOffset.Enable = "on";
            app.TimeWindow.Enable = "on";
            app.setWindow(0, app.getCurrentDuration());
        end

        function n = numberOfFiles(app)
            n = numel(app.Files);
        end

        function signal = convertToPhysical(app, signal, idx)
            hdr = app.getFile().Fileinfo;
            if isfield(hdr, "DigitalMin")
                DigitalMin  = hdr.DigitalMin(idx);
                DigitalMax  = hdr.DigitalMax(idx);
                PhysicalMin = hdr.PhysicalMin(idx);
                PhysicalMax = hdr.PhysicalMax(idx);
    
                signal = (signal - DigitalMin) .* ...
                    (PhysicalMax - PhysicalMin) ./ ...
                    (DigitalMax - DigitalMin) + PhysicalMin;
            end
        end

        function ok = checkFile(app)
            ok = false;

            if isempty(app.getFile())
                uialert(app.UIFigure, ...
                    "Load an EDF file first", "Signal Error")
                return
            end

            ok = true;
        end

        function ok = checkFileElectrode(app)
            ok = app.checkFile();

            if isempty(app.ElectrodeList.Value)
                uialert(app.UIFigure, ...
                    "Choose an electrode first", "Signal Error")
                return
            end

            ok = true;
        end

        function idx = getElectrodeIndex(app, electrode)
            rname = strcat("EEG", electrode, "_LE");
            idx = find( ...
                strcmp(app.getFile().Record.Properties.VariableNames, ...
                       rname), 1);
        end
    end
    
    methods (Access = private)
        function refreshFilesListBox(app)
            n = numel(app.Files);
            if n == 0
                app.FileList.Items = {};
                app.FileList.ItemsData = [];
                app.FileList.Value = {};
                return
            end

            app.FileList.Items = cellfun(@(f) f.Fileinfo.Filename, app.Files);
            app.FileList.ItemsData = 1:n;
            app.FileList.Value = n;

            app.selectFile(n);
        end
        
        function updateElectrodeList(app)
            selRegions = string(app.RegionList.Value);

            if isempty(selRegions)
                app.ElectrodeList.Items = strings(0,1);
                app.ElectrodeList.Value = {};
                return
            end

            electrodes = strings(0,1);

            for r = 1:numel(selRegions)
                regName = selRegions(r);
        
                if isfield(app.Regions, regName)
                    s = app.Regions.(regName);
                    electrodes = [electrodes; string(fieldnames(s))];
                end
            end
        
            electrodes = unique(electrodes, "stable");
        
            app.ElectrodeList.Items = electrodes;
        
            if isempty(electrodes)
                app.ElectrodeList.Value = {};
            else
                app.ElectrodeList.Value = electrodes(1);
            end
        end

        function duration = getCurrentDuration(app)
            file = app.getFile();
            if isempty(file)
                duration = 0;
                return
            end
        
            fi = file.Fileinfo;
        
            duration = seconds(fi.DataRecordDuration) * fi.NumDataRecords;
        end
        
        function setWindow(app, offset, width)
            if app.IsSliderSyncing
                return
            end

            duration = app.getCurrentDuration();
            if duration <= 0
                return
            end
        
            if width < 1
                width = 1;
            end
        
            if width > duration
                width = duration;
            end
        
            if offset < 0
                offset = 0;
            end
        
            if offset + width > duration
                offset = duration - width;
            end
        
            endTime = offset + width;
        
            app.IsSliderSyncing = true;
        
            app.CurrentWidth = width;
        
            app.TimeSlider.Limits = [0 duration];
            app.TimeSlider.Value = [offset, endTime];
        
            app.TimeOffset.Limits = [0 duration - 1];
            app.TimeOffset.Value = offset;
        
            app.TimeWindow.Limits = [1 duration - offset];
            app.TimeWindow.Value = width;
        
            app.IsSliderSyncing = false;

            drawnow limitrate;
        end

        function updateTimeLimits(app, offset)
            duration = app.getCurrentDuration();
            if duration <= 0
                return
            end
        
            width = min(10, duration);
            app.setWindow(offset, width);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Regions.Frontal = struct( ...
                Fp1="EEG Fp1-LE", Fp2="EEG Fp2-LE", F3="EEG F3-LE", ...
                F4="EEG F4-LE", F7="EEG F7-LE", F8="EEG F8-LE", Fz="EEG Fz-LE");
            app.Regions.Temporal = struct( ...
                T3="EEG T3-LE", T4="EEG T4-LE", T5="EEG T5-LE", T6="EEG T6-LE");
            app.Regions.Parietal = struct( ...
                P3="EEG P3-LE", P4="EEG P4-LE", Pz="EEG Pz-LE");
            app.Regions.Occipital = struct( ...
                O1="EEG O1-LE", O2="EEG O2-LE");
            app.Regions.Central = struct( ...
                C3="EEG C3-LE", C4="EEG C4-LE", Cz="EEG Cz-LE");

            app.AsymPairs.FrontalAsym   = [ "Fp1","Fp2"; "F3","F4"; "F7","F8" ];
            app.AsymPairs.TemporalAsym  = [ "T3","T4"; "T5","T6" ];
            app.AsymPairs.ParietalAsym  = [ "P3","P4" ];
            app.AsymPairs.OccipitalAsym = [ "O1","O2" ];
            app.AsymPairs.CentralAsym   = [ "C3","C4" ];

            app.RegionList.Items = fieldnames(app.Regions);
            app.RegionList.Value = "Frontal";
            app.updateElectrodeList();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filenames, pathname] = uigetfile("*.edf", ...
                "Select an EEG file", MultiSelect='on');

            if isequal(filenames, 0)
                return
            end

            if ischar(filenames)
                filenames = {filenames};
            end

            d = uiprogressdlg(app.UIFigure, ...
                Title="Opening files", Message="Please wait...");

            for k = 1:numel(filenames)
                filepath = fullfile(pathname, filenames{k});

                if iscell(app.Files)
                    already = any(cellfun(@(f) strcmp(f.Filepath, filepath), app.Files));
                else
                    already = any(arrayfun(@(f) strcmp(f.Filepath, filepath), app.Files));
                end

                if already
                    continue
                end

                [record, ~] = edfread(filepath);
                fileinfo = edfinfo(filepath);
        
                file = struct();
                file.Record = record;
                file.Fileinfo = fileinfo;
                file.Filepath = filepath;
    
                app.Files{end+1} = file;

                d.Value = min(1, d.Value + 1/numel(filenames));
            end
            d.Value = 1;
            close(d);

            app.refreshFilesListBox();
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end

        % Value changed function: RegionList
        function RegionListValueChanged(app, event)
            app.updateElectrodeList();
        end

        % Value changed function: FileList
        function FileListValueChanged(app, event)
            selectFile(app, app.FileList.Value);
        end

        % Menu selected function: GraphMenu
        function GraphMenuSelected(app, event)
            Analysis.Graph(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            if app.IsSliderSyncing
                return
            end

            duration = app.getCurrentDuration();
        
            newOffset = event.Value(1);
            newEnd = event.Value(2);

            currentOffset = app.TimeOffset.Value;
            newWidth = newEnd - currentOffset;
            currentEnd = currentOffset + app.CurrentWidth;
        
            if newOffset ~= currentOffset && newEnd ~= currentEnd
                app.setWindow(currentOffset, app.CurrentWidth);
            elseif newOffset ~= currentOffset
                if newOffset + app.CurrentWidth > duration
                    app.setWindow(currentOffset, app.CurrentWidth);
                else
                    app.setWindow(newOffset, app.CurrentWidth);
                end
            elseif newEnd ~= currentEnd
                app.setWindow(currentOffset, newWidth);
            end
        end

        % Value changed function: TimeOffset
        function TimeOffsetValueChanged(app, event)
            app.setWindow(app.TimeOffset.Value, app.CurrentWidth);
        end

        % Value changed function: TimeWindow
        function TimeWindowValueChanged(app, event)
            app.setWindow(app.TimeOffset.Value, app.TimeWindow.Value);
        end

        % Menu selected function: ApEnMenu
        function ApEnMenuSelected(app, event)
            Analysis.ApEn(app);
        end

        % Menu selected function: STFTMenu
        function STFTMenuSelected(app, event)
            Analysis.STFT(app);
        end

        % Menu selected function: CWTMenu
        function CWTMenuSelected(app, event)
            Analysis.CWT(app);
        end

        % Menu selected function: AmplitudeMenu
        function AmplitudeMenuSelected(app, event)
            Analysis.TopoAmp(app);
        end

        % Menu selected function: FrequencyMenu
        function FrequencyMenuSelected(app, event)
            Analysis.TopoFreq(app);
        end

        % Menu selected function: EntropyApEnMenu
        function EntropyApEnMenuSelected(app, event)
            Analysis.TopoEn(app);
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            Export.exportData(app);
        end

        % Menu selected function: ClosefilesMenu
        function ClosefilesMenuSelected(app, event)
            uialert(app.UIFigure, "TODO", "Error")
        end

        % Menu selected function: ExportasymMenu
        function ExportasymMenuSelected(app, event)
            Export.Asym.exportData(app);
        end

        % Menu selected function: PwelchMenu
        function PwelchMenuSelected(app, event)
            Analysis.Pwelch(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Accelerator = 'e';
            app.ExportMenu.Text = 'Export';
            app.ExportasymMenu = uimenu(app.FileMenu);
            app.ExportasymMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportasymMenuSelected, true);
            app.ExportasymMenu.Text = 'Export (asym)';
            app.ClosefilesMenu = uimenu(app.FileMenu);
            app.ClosefilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosefilesMenuSelected, true);
            app.ClosefilesMenu.Separator = 'on';
            app.ClosefilesMenu.Accelerator = 'w';
            app.ClosefilesMenu.Text = 'Close file(s)';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Accelerator = 'q';
            app.QuitMenu.Text = 'Quit';
            app.AnalysisMenu = uimenu(app.UIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.GraphMenu = uimenu(app.AnalysisMenu);
            app.GraphMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphMenuSelected, true);
            app.GraphMenu.Accelerator = 'g';
            app.GraphMenu.Text = 'Graph';
            app.ApEnMenu = uimenu(app.AnalysisMenu);
            app.ApEnMenu.MenuSelectedFcn = createCallbackFcn(app, @ApEnMenuSelected, true);
            app.ApEnMenu.Text = 'ApEn';
            app.PwelchMenu = uimenu(app.AnalysisMenu);
            app.PwelchMenu.MenuSelectedFcn = createCallbackFcn(app, @PwelchMenuSelected, true);
            app.PwelchMenu.Text = 'Pwelch';
            app.STFTMenu = uimenu(app.AnalysisMenu);
            app.STFTMenu.MenuSelectedFcn = createCallbackFcn(app, @STFTMenuSelected, true);
            app.STFTMenu.Text = 'STFT';
            app.CWTMenu = uimenu(app.AnalysisMenu);
            app.CWTMenu.MenuSelectedFcn = createCallbackFcn(app, @CWTMenuSelected, true);
            app.CWTMenu.Text = 'CWT';
            app.TopographyMenu = uimenu(app.AnalysisMenu);
            app.TopographyMenu.Text = 'Topography';
            app.AmplitudeMenu = uimenu(app.TopographyMenu);
            app.AmplitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @AmplitudeMenuSelected, true);
            app.AmplitudeMenu.Text = 'Amplitude';
            app.FrequencyMenu = uimenu(app.TopographyMenu);
            app.FrequencyMenu.MenuSelectedFcn = createCallbackFcn(app, @FrequencyMenuSelected, true);
            app.FrequencyMenu.Text = 'Frequency';
            app.EntropyApEnMenu = uimenu(app.TopographyMenu);
            app.EntropyApEnMenu.MenuSelectedFcn = createCallbackFcn(app, @EntropyApEnMenuSelected, true);
            app.EntropyApEnMenu.Text = 'Entropy (ApEn)';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.MainGrid);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.GridLayout = uigridlayout(app.FilesTab);
            app.FileList = uilistbox(app.GridLayout);
            app.FileList.Items = {};
            app.FileList.ValueChangedFcn = createCallbackFcn(app, @FileListValueChanged, true);
            app.FileList.Value = {};
            app.ElectrodesTab = uitab(app.TabGroup);
            app.ElectrodesTab.Title = 'Electrodes';
            app.GridLayout2 = uigridlayout(app.ElectrodesTab);
            app.RegionList = uilistbox(app.GridLayout2);
            app.RegionList.Items = {''};
            app.RegionList.Multiselect = 'on';
            app.RegionList.ValueChangedFcn = createCallbackFcn(app, @RegionListValueChanged, true);
            app.RegionList.Value = {};
            app.ElectrodeList = uilistbox(app.GridLayout2);
            app.ElectrodeList.Items = {};
            app.ElectrodeList.Multiselect = 'on';
            app.ElectrodeList.Value = {};
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.Axes = uiaxes(app.GridLayout3);
            title(app.Axes, 'No graph')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TimeSlider = uislider(app.GridLayout4, 'range');
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Step = 1;
            app.TimeSlider.Enable = 'off';
            app.TimeSlider.Value = [0 0];
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.TimeOffset = uispinner(app.GridLayout5);
            app.TimeOffset.Limits = [0 0];
            app.TimeOffset.ValueChangedFcn = createCallbackFcn(app, @TimeOffsetValueChanged, true);
            app.TimeOffset.Enable = 'off';
            app.TimeWindow = uispinner(app.GridLayout5);
            app.TimeWindow.Limits = [0 0];
            app.TimeWindow.ValueChangedFcn = createCallbackFcn(app, @TimeWindowValueChanged, true);
            app.TimeWindow.Enable = 'off';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.BandSel = uidropdown(app.GridLayout6);
            app.BandSel.Items = {'No filtering', 'Gamma', 'Beta', 'Alpha', 'Theta', 'Delta'};
            app.BandSel.ItemsData = {'0', '1', '2', '3', '4', '5'};
            app.BandSel.Value = '0';
            app.MWSel = uidropdown(app.GridLayout6);
            app.MWSel.Items = {'db1', 'db2', 'db3', 'db4', 'sym2', 'sym4', 'dmey'};
            app.MWSel.Value = 'db4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end